classdef SnakeAppDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        direction = 0; % direction = 0 is left direction
        score = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            dim = 16; %create the dimensions
            app.score = 0; %initialize the original score of the app to 0
            app.direction = 0; %left direction
            cla(app.UIAxes)

            v=0.9;
            f=[0 0] %vector for the two positions
            f(1) = round(rand*dim);
            f(2) = round(rand*dim);

            s=zeros (100,2); %define snake vector
            s(1,1) = round(dim/2);
            s(1,2) = round(dim/2);
            s(2,1) = 5;
            s(2,2) = 6;

            snake = plot(app.UIAxes, s ([1 2],1), s ([1 2], 2), 'Marker', ".", "MarkerSize", 20, 'LineStyle',"-", "LineWidth",15, 'Color','4CCB32')
            hold (app.UIAxes, 'on')
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SnakeAppDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end